classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        spectralWidthPlane   matlab.ui.control.UIAxes
        SpectraWidthLabel    matlab.ui.control.Label
        spectralWidthSlider  matlab.ui.control.Slider
    end


    methods (Access = private)
    
        function F = func(app)
            load('G:\My Drive\Research\Research Trips\2018 Mar San Francisco SRI\Scrap\monteCarloData.mat','data');
            data.mean.SNR = data.mean.signal./data.mean.noise;
            data.fracError.signal = data.std.signal./data.mean.signal;
            data.fracError.noise = data.std.noise./data.mean.noise;
            data.fracError.SNR = data.fracError.signal+abs(data.fracError.noise);
            F = scatteredInterpolant(data.exp.integrationTime(:),data.mean.SNR(:),data.mean.fWidth(:),...
                data.fracError.SNR(:),'linear','none');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changing function: spectralWidthSlider
        function spectralWidthSliderValueChanging(app, event)
            changingValue = event.Value;
             xIntegrationTime = 0.5:0.1:5;
            ySNR = 0.1:0.01:2;
            
%             value = app.spectralWidthSlider.Value;
            
            zfWidth = changingValue;
            [X,Y,Z]=meshgrid(xIntegrationTime,ySNR,zfWidth);
            
            if ~exist('F','var')
                F = func(app);
            end
            fracError = F(X,Y,Z);
            h=pcolor(app.spectralWidthPlane,X,Y,fracError);
            
            app.spectralWidthPlane.XLabel.String='Integration Time [s]';
            app.spectralWidthPlane.YLabel.String='SNR [ratio]';
            
            set(h, 'EdgeColor', 'none');
            app.spectralWidthPlane.Colormap=flipud(jet);
            
            app.spectralWidthPlane.CLim=[0 0.5];
            colorbar(app.spectralWidthPlane)
            
            
%             h1 = colorbar;
%             ylabel(h1, 'Fractional Error in SNR')
%             title(['Spectral Width = ', num2str(zfWidth),' Hz']);
            
            app.spectralWidthPlane.YLim = [0 2];
            app.spectralWidthPlane.XLim = [0.5 5];
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.spectralWidthPlane = uiaxes(app.UIFigure);
            title(app.spectralWidthPlane, 'Fractional Error')
            xlabel(app.spectralWidthPlane, 'Integration Time [s]')
            ylabel(app.spectralWidthPlane, 'SNR')
            app.SpectraWidthLabel = uilabel(app.UIFigure);
            app.SpectraWidthLabel.Text = 'Spectra Width';
            app.spectralWidthSlider = uislider(app.UIFigure);
            app.spectralWidthSlider.Limits = [1 100];
            app.spectralWidthSlider.MajorTicks = [1 10 20 30 40 50 60 70 80 100];
            app.spectralWidthSlider.ValueChangingFcn = createCallbackFcn(app, @spectralWidthSliderValueChanging, true);
            app.spectralWidthSlider.Value = 30;
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end